classdef calibration_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        savecalibrationfilematButton  matlab.ui.control.Button
        CalibrationToolLabel          matlab.ui.control.Label
        rawformatPanel                matlab.ui.container.Panel
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        bitdepthEditFieldLabel        matlab.ui.control.Label
        bitdepthEditField             matlab.ui.control.NumericEditField
        offsetEditFieldLabel          matlab.ui.control.Label
        offsetEditField               matlab.ui.control.NumericEditField
        bayerorderDropDownLabel       matlab.ui.control.Label
        bayerorderDropDown            matlab.ui.control.DropDown
        dataformatDropDownLabel       matlab.ui.control.Label
        dataformatDropDown            matlab.ui.control.DropDown
        modulePanel                   matlab.ui.container.Panel
        blacklevelButton              matlab.ui.control.Button
        shadingcorrectionButton       matlab.ui.control.Button
        colorcorrectonButton          matlab.ui.control.Button
        modulePanel_2                 matlab.ui.container.Panel
        autowhitebalanceButton        matlab.ui.control.Button
        noiseprofileButton            matlab.ui.control.Button
        gammaEditFieldLabel           matlab.ui.control.Label
        gammaEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Property % Description
        
        input_frame;
        
        input_black_frame;
        input_ls_frame;
        input_ccm_frame;
        
        dsmc_algo;
        blc_type;
        im_info;
        isp;
        params; 
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        app.input_frame;
        app.input_black_frame;
        app.input_ls_frame;
        app.input_ccm_frame;
        
        app.im_info;
        app.im_info.rawWidth = app.WidthEditField.Value;
        app.im_info.rawHeight = app.HeightEditField.Value;
        app.im_info.bit_depth = app.bitdepthEditField.Value;
        app.im_info.pixel_offset = app.offsetEditField.Value;
        app.im_info.data_format = app.dataformatDropDown.Value;
        app.im_info.bayer = app.bayerorderDropDown.Value;
        app.im_info.type = 'raw';
        app.im_info.cell = 'Bayer';
        
        app.params;
        app.params.blc = 0;
        app.params.ls_map = [];
        app.params.ccm = [];
        %app.params.blc_enable=0;
        %app.params.lsc_enable=0;
        %app.params.ccm_enable=0;
        
        app.isp;
        app.isp.dsmc_algo = 'Hamilton & Adams';
        app.isp.CCM = [1 0 0; 0 1 0; 0 0 1];
        app.isp.WB = [1 1 1];
        app.isp.gamma = 2.2 ;
        app.isp.dg = 1;
        app.isp.bkd = 0;
        app.isp.lsc = [];

        end

        % Button pushed function: blacklevelButton
        function blacklevelButtonPushed(app, event)
            % black level correction
            app.blacklevelButton.FontColor = 'black';
            
            addpath(genpath('.\'));
            im_info = app.im_info;
            
            [FileName, PathName] = uigetfile([cd,'\','*.raw*'],'Select the black level raw image');
            app.input_black_frame = [PathName, FileName];
            
            [raw_black, im_info]= readImg(app.input_black_frame, im_info);
            
            blc = round(mean2(raw_black));
            
            ch1 = raw_black(1:2:end, 1:2:end);  % (1, 1)
            ch2 = raw_black(1:2:end, 2:2:end);  % (1, 2)
            ch3 = raw_black(2:2:end, 1:2:end);  % (2, 1)
            ch4 = raw_black(2:2:end, 2:2:end);  % (2, 2)
            
            ch1_blc = round(mean2(ch1));
            ch2_blc = round(mean2(ch2));
            ch3_blc = round(mean2(ch3));
            ch4_blc = round(mean2(ch4));
            
%             app.isp.bkd = blc;
            app.isp.bkd = [ch1_blc, ch2_blc, ch3_blc, ch4_blc];

            app.params.blc = app.isp.bkd;
            
            app.blacklevelButton.FontColor = 'red';
        end

        % Button pushed function: shadingcorrectionButton
        function shadingcorrectionButtonPushed(app, event)
            % lens shading correction
            app.shadingcorrectionButton.FontColor = 'black';
            
            addpath(genpath('.\'));
            im_info = app.im_info;
            
            [FileName, PathName] = uigetfile([cd,'\','*.raw*'],'Select the lens shading correction raw image');
            app.input_ls_frame =  [PathName, FileName];
            
            [temp_raw, im_info] = readImg(app.input_ls_frame, im_info);
            
%             temp_raw = temp_raw - app.params.blc;
            temp_raw(1:2:end, 1:2:end) = temp_raw(1:2:end, 1:2:end) - app.params.blc(1);  % (1, 1)
            temp_raw(1:2:end, 2:2:end) = temp_raw(1:2:end, 2:2:end) - app.params.blc(2);  % (1, 2)
            temp_raw(2:2:end, 1:2:end) = temp_raw(2:2:end, 1:2:end) - app.params.blc(3);  % (2, 1)
            temp_raw(2:2:end, 2:2:end) = temp_raw(2:2:end, 2:2:end) - app.params.blc(4);  % (2, 2)
            
            [CH , temp] = splitChannel(temp_raw, im_info.bayer);
            
            resize = 100;  % DebugMK
            for i = 1:4
                temp = [];
                temp(:,:) = imresize(CH(:,:,i), [im_info.rawHeight/2/resize, im_info.rawWidth/2/resize]);  % DebugMK
                temp_lp(:,:,i) = imresize(temp, [im_info.rawHeight/2, im_info.rawWidth/2]);
            end
            
            % Use center 64*64 pixels for calibration
            img_center = [];
            app.isp.lsc = [];
            ratio_map = [];
            for i = 1:4
                img_center(i) = mean(mean(temp_lp(im_info.rawHeight/4-31:im_info.rawHeight/4+32, im_info.rawWidth/4-31:im_info.rawWidth/4+32, i),'omitnan'),'omitnan');
                ratio_map(:,:,i) = img_center(i)./temp_lp(:,:,i);
            end
            
            app.isp.lsc = combineChannels(ratio_map);
            app.params.ls_map = app.isp.lsc;
            
            app.shadingcorrectionButton.FontColor='red';
            
        end

        % Button pushed function: colorcorrectonButton
        function colorcorrectonButtonPushed(app, event)
            % ccm
            app.colorcorrectonButton.FontColor ='black';
            
            addpath(genpath('.\'));
            
            im_info = app.im_info;
            isp = app.isp;
            params = app.params;
            
            [FileName, PathName] = uigetfile([cd, '\', '*.raw*'], 'Select the ccm correction raw image');
            app.input_ccm_frame = [PathName, FileName];
            [~, params.file_name, ~] = fileparts(app.input_ccm_frame);
            
            [img, im_info] = readImg(app.input_ccm_frame, im_info);

            CH_rggb = reorgRawBayer(img, im_info);
            
            figure(1);
            clf;
            imshow(CH_rggb(:,:,2), [0 2^(im_info.bit_depth)-1]);
            title('Color Checker Chart');
            
            m = msgbox('Click and drag a rectangle to cover all the patches, double click the rectangle when you are done.');
            uiwait(m);
            r = imrect;
            rec_big = uint16(wait(r));
            
            square = rec_big(3)/12;
            edge_hori = square/1.8;
            edge_vert = square/2;
            
            space_hori = (rec_big(3)-square*6-edge_hori*2)/5;
            space_vert = (rec_big(4)-square*4-edge_vert*2)/3;
            
            rec_small = [];
            h = [];
            for i = 1:4
                for j = 1:6
                    rec_temp = [rec_big(1)+edge_hori+(space_hori+square)*(j-1), rec_big(2)+edge_vert+(space_vert+square)*(i-1), square, square];
                    
                    k = (i-1)*6+j;
                    rec_small(k,:) = rec_temp;
%                     patch_roi_row(k,:) = (rec_temp(2)-square/3)*2:(rec_temp(2)+rec_temp(4)+square/3)*2;
%                     patch_roi_col(k,:) = (rec_temp(1)-square/3)*2:(rec_temp(1)+rec_temp(3)+square/3)*2;
                    h(k) = rectangle('Position', rec_temp, 'EdgeColor', 'red');
                end
            end
            
            isp.rec_big = rec_big;            
            [img_gamma, data_out] = ISP_pipeline_02(img, im_info, isp, params);
             
            app.params.ccm = data_out.CCM;
            app.params.wb = data_out.WB;
            
            app.colorcorrectonButton.FontColor ='red';
        
        end

        % Button pushed function: savecalibrationfilematButton
        function savecalibrationfilematButtonPushed(app, event)
            params= app.params;
            save('calibration_dat.mat', 'params');
        end

        % Value changed function: gammaEditField
        function gammaEditFieldValueChanged(app, event)
            value = app.gammaEditField.Value;
            
            app.isp.gamma = app.gammaEditField.Value;
            app.params.gamma = app.isp.gamma;
            
        end

        % Value changed function: bayerorderDropDown
        function bayerorderDropDownValueChanged(app, event)
            value = app.bayerorderDropDown.Value;
            
            app.im_info.bayer= app.bayerorderDropDown.Value;
            
        end

        % Value changed function: dataformatDropDown
        function dataformatDropDownValueChanged(app, event)
            value = app.dataformatDropDown.Value;
            
            app.im_info.data_format = app.dataformatDropDown.Value;
            
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            
            app.im_info.rawWidth = app.WidthEditField.Value;
            
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            
            app.im_info.rawHeight = app.HeightEditField.Value;
            
        end

        % Value changed function: bitdepthEditField
        function bitdepthEditFieldValueChanged(app, event)
            value = app.bitdepthEditField.Value;
            
            app.im_info.bit_depth = app.bitdepthEditField.Value;
            
        end

        % Value changed function: offsetEditField
        function offsetEditFieldValueChanged(app, event)
            value = app.offsetEditField.Value;
            
            app.im_info.pixel_offset = app.offsetEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.savecalibrationfilematButton = uibutton(app.UIFigure, 'push');
            app.savecalibrationfilematButton.ButtonPushedFcn = createCallbackFcn(app, @savecalibrationfilematButtonPushed, true);
            app.savecalibrationfilematButton.Text = 'save calibration file .mat';
            app.CalibrationToolLabel = uilabel(app.UIFigure);
            app.CalibrationToolLabel.Text = 'Calibration Tool';
            app.rawformatPanel = uipanel(app.UIFigure);
            app.rawformatPanel.Title = 'raw format';
            app.WidthEditFieldLabel = uilabel(app.rawformatPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.rawformatPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 1920;
            app.HeightEditFieldLabel = uilabel(app.rawformatPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.rawformatPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 1080;
            app.bitdepthEditFieldLabel = uilabel(app.rawformatPanel);
            app.bitdepthEditFieldLabel.Text = 'bit depth';
            app.bitdepthEditField = uieditfield(app.rawformatPanel, 'numeric');
            app.bitdepthEditField.ValueChangedFcn = createCallbackFcn(app, @bitdepthEditFieldValueChanged, true);
            app.bitdepthEditField.Value = 12;
            app.offsetEditFieldLabel = uilabel(app.rawformatPanel);
            app.offsetEditFieldLabel.Text = 'offset';
            app.offsetEditField = uieditfield(app.rawformatPanel, 'numeric');
            app.offsetEditField.ValueChangedFcn = createCallbackFcn(app, @offsetEditFieldValueChanged, true);
            app.bayerorderDropDownLabel = uilabel(app.rawformatPanel);
            app.bayerorderDropDownLabel.Text = 'bayer order';
            app.bayerorderDropDown = uidropdown(app.rawformatPanel);
            app.bayerorderDropDown.Items = {'rggb', 'grbg', 'gbrg', 'bggr'};
            app.bayerorderDropDown.ValueChangedFcn = createCallbackFcn(app, @bayerorderDropDownValueChanged, true);
            app.bayerorderDropDown.Value = 'rggb';
            app.dataformatDropDownLabel = uilabel(app.rawformatPanel);
            app.dataformatDropDownLabel.Text = 'data format';
            app.dataformatDropDown = uidropdown(app.rawformatPanel);
            app.dataformatDropDown.Items = {'ieee-ie', 'ieee-be', ' '};
            app.dataformatDropDown.ValueChangedFcn = createCallbackFcn(app, @dataformatDropDownValueChanged, true);
            app.dataformatDropDown.Value = 'ieee-ie';
            app.modulePanel = uipanel(app.UIFigure);
            app.modulePanel.Title = 'module';
            app.blacklevelButton = uibutton(app.modulePanel, 'push');
            app.blacklevelButton.ButtonPushedFcn = createCallbackFcn(app, @blacklevelButtonPushed, true);
            app.blacklevelButton.Text = 'blacklevel';
            app.shadingcorrectionButton = uibutton(app.modulePanel, 'push');
            app.shadingcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @shadingcorrectionButtonPushed, true);
            app.shadingcorrectionButton.Text = 'shading correction';
            app.colorcorrectonButton = uibutton(app.modulePanel, 'push');
            app.colorcorrectonButton.ButtonPushedFcn = createCallbackFcn(app, @colorcorrectonButtonPushed, true);
            app.colorcorrectonButton.Text = {'color correcton'; ''};
            app.modulePanel_2 = uipanel(app.UIFigure);
            app.modulePanel_2.Title = 'module';
            app.autowhitebalanceButton = uibutton(app.modulePanel_2, 'push');
            app.autowhitebalanceButton.Text = {'auto white balance'; ''};
            app.noiseprofileButton = uibutton(app.modulePanel_2, 'push');
            app.noiseprofileButton.Text = 'noise profile';
            app.gammaEditFieldLabel = uilabel(app.modulePanel_2);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.modulePanel_2, 'numeric');
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @gammaEditFieldValueChanged, true);
            app.gammaEditField.Value = 2.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibration_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end